classdef TMD_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TMDfrequencySlider           matlab.ui.control.Slider
        TMDfrequencySliderLabel      matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        TMDDampingFactorSlider       matlab.ui.control.Slider
        TMDDampingFactorSliderLabel  matlab.ui.control.Label
        TMDMassSlider                matlab.ui.control.Slider
        TMDMassSliderLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TMDMassSlider
        function TMDMassSliderValueChanged(app, event)

     
        end

        % Callback function: TMDDampingFactorSlider, TMDMassSlider
        function TMDDampingFactorSliderValueChanged(app, event)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
[file_i,xy,nnod,sizew,idb,ndof,incid,l,gamma,m,EA,EJ,posit,nbeam,pr]=loadstructure('TRUSS_BRIDGE');
[M,K]=assem(incid,l,m,EA,EJ,gamma,idb);

[MFF, MFC, MCC] = freefree(M, ndof);
[KFF, KFC, KCC] = freefree(K, ndof);

[x0, omega_squared] = eig(MFF\KFF);
omega = diag(sqrt(omega_squared));
[omega, ind] = sort(omega);
modes = x0(:,ind);

B = [0.01 0.0075]';
A = zeros(2,2);
for ii=1:2
    A(ii,:) = [1/(2*omega(ii)) omega(ii)/2];
end
ab = A\B;
C = ab(1)*M + ab(2)*K;
[CFF, CFC, CCC] = freefree(C, ndof);

F0 = zeros(ndof, 1);
F0(idb(6,2)) = 1; 
om = (0:0.01:7)*2*pi; 

X = zeros(ndof, length(om));
for ii = 1:length(om)
    A = -om(ii)^2*MFF + 1i*om(ii)*CFF + KFF;
    X(:,ii) = A\F0;
end

ml = app.TMDMassSlider.Value;
hl = app.TMDDampingFactorSlider.Value;
wl = app.TMDfrequencySlider.Value*2*pi;

kl = ml * wl^2;
cl = hl * 2 * ml * wl;

[file_i2,xy2,nnod2,sizew2,idb2,ndof2,incid2,l2,gamma2,m2,EA2,EJ2,posit2,nbeam2,pr2]=loadstructure('TRUSS_BRIDGE_TMD');
[M_TMD,K_TMD]=assem(incid2,l2,m2,EA2,EJ2,gamma2,idb2);

E_ml = zeros(1,3*nnod2); 
E_ml(1,idb2(37,2)) = 1;
M_TMD = M_TMD + E_ml'*ml*E_ml;

K_k_L = [1 0 0 -1 0 0]' * kl * [1 0 0 -1 0 0];
g = 3*pi/2;
lambda_k = [cos(g)  sin(g) 0
            -sin(g) cos(g) 0
            0       0      1];
Lambda_k = [lambda_k zeros(3,3)
            zeros(3,3) lambda_k ];
K_k_G = Lambda_k' * K_k_L * Lambda_k;

idofn2 = idb2(37,:);
idofn6 = idb2(7,:);
idofk = [idofn2 idofn6];

K_TMD(idofk, idofk) = K_TMD(idofk, idofk) + K_k_G;

C_TMD = ab(1)*M_TMD + ab(2)*K_TMD;

C_c_L = [1 0 0 -1 0 0]' * cl * [1 0 0 -1 0 0];
C_c_G = Lambda_k' * C_c_L * Lambda_k;

C_TMD(idofk, idofk) = C_TMD(idofk, idofk) + C_c_G;

MFF_TMD = freefree(M_TMD, ndof2);
KFF_TMD = freefree(K_TMD, ndof2);
CFF_TMD = freefree(C_TMD, ndof2);

F0_TMD = zeros(ndof2,1);
F0_TMD(idb2(6,2)) = 1;

X_TMD = zeros(ndof2, length(om));
for ii = 1:length(om)
    A = -om(ii)^2*MFF_TMD + 1i*om(ii)*CFF_TMD + KFF_TMD;
    X_TMD(:,ii) = A\F0_TMD;
end

reduction = (1- max(abs(X_TMD(idb2(6,2),200:300)))/max(abs(X(idb2(6,2),200:300))))*100;

plot(app.UIAxes,om/(2*pi), abs(X(idb2(6,2),:)), 'LineWidth', 1.5)
hold(app.UIAxes,"on")
plot(app.UIAxes,om/(2*pi), abs(X_TMD(idb2(6,2),:)), 'LineWidth', 1.5)
hold(app.UIAxes,"off")
title(app.UIAxes,['FRF with ml=',num2str(ml),' hl=',num2str(hl), ' reduction=',num2str(reduction)])
xlabel(app.UIAxes,"Frequency")
ylabel(app.UIAxes,"Magnitude")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ttt')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TMDMassSliderLabel = uilabel(app.UIFigure);
            app.TMDMassSliderLabel.Text = 'TMD Mass';
            app.TMDMassSlider = uislider(app.UIFigure);
            app.TMDMassSlider.Limits = [10 5982.2];
            app.TMDMassSlider.ValueChangedFcn = createCallbackFcn(app, @TMDMassSliderValueChanged, true);
            app.TMDMassSlider.ValueChangingFcn = createCallbackFcn(app, @TMDDampingFactorSliderValueChanged, true);
            app.TMDMassSlider.Value = 10;
            app.TMDDampingFactorSliderLabel = uilabel(app.UIFigure);
            app.TMDDampingFactorSliderLabel.Text = 'TMD Damping Factor';
            app.TMDDampingFactorSlider = uislider(app.UIFigure);
            app.TMDDampingFactorSlider.Limits = [0.0001 0.3];
            app.TMDDampingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @TMDDampingFactorSliderValueChanged, true);
            app.TMDDampingFactorSlider.Value = 0.0001;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TMDfrequencySliderLabel = uilabel(app.UIFigure);
            app.TMDfrequencySliderLabel.Text = 'TMD frequency';
            app.TMDfrequencySlider = uislider(app.UIFigure);
            app.TMDfrequencySlider.Limits = [2.2 2.8];
            app.TMDfrequencySlider.Value = 2.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMD_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end